classdef finishedGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        CalculationTab             matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        msLabel                    matlab.ui.control.Label
        InitialCoordinatesLabel    matlab.ui.control.Label
        FinalCoordinatesLabel      matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        VelocityEditField          matlab.ui.control.NumericEditField
        VelocityEditFieldLabel     matlab.ui.control.Label
        xEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        FirstGuessEditFieldLabel   matlab.ui.control.Label
        FirstGuessEditField        matlab.ui.control.NumericEditField
        SecondGuessEditFieldLabel  matlab.ui.control.Label
        SecondGuessEditField       matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        xEditField_2Label          matlab.ui.control.Label
        xFinalEditField            matlab.ui.control.NumericEditField
        yEditField_2Label          matlab.ui.control.Label
        yFinalEditField            matlab.ui.control.NumericEditField
        zEditField_2Label          matlab.ui.control.Label
        zFinalEditField            matlab.ui.control.NumericEditField
        FinalAngleEditFieldLabel   matlab.ui.control.Label
        FinalAngleEditField        matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel     matlab.ui.control.Label
        TimeStepEditField          matlab.ui.control.NumericEditField
        YawEditFieldLabel          matlab.ui.control.Label
        YawEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        
    end
    
    methods (Access = public)
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              [box1,box2,box3,box4,box5,line1,line2,net,post1,post2] = courtPlotter();
              view(3);
              plot3(app.UIAxes,box1(1,:),box1(3,:),box1(2,:),'k');
              hold(app.UIAxes, 'on');
              app.UIAxes.XLim = [-6.7 6.7];
              app.UIAxes.ZLim = [0 5];
              plot3(app.UIAxes,box2(1,:),box2(3,:),box2(2,:),'k');
              plot3(app.UIAxes,box3(1,:),box3(3,:),box3(2,:),'k');
              plot3(app.UIAxes,box4(1,:),box4(3,:),box4(2,:),'k');
              plot3(app.UIAxes,box5(1,:),box4(3,:),box4(2,:),'k');
              plot3(app.UIAxes,line1(1,:),line1(3,:),line1(2,:),'k');
              plot3(app.UIAxes,line2(1,:),line2(3,:),line2(2,:),'k');
              plot3(app.UIAxes,net(1,:),net(3,:),net(2,:),'k');
              plot3(app.UIAxes,post1(1,:),post1(3,:),post1(2,:),'k');
              plot3(app.UIAxes,post2(1,:),post2(3,:),post2(2,:),'k');
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            u0 = app.VelocityEditField.Value;
            r0 = [app.xEditField.Value;app.yEditField.Value;app.zEditField.Value];
            theta = [app.FirstGuessEditField.Value;app.SecondGuessEditField.Value];
            phi = app.YawEditField.Value;
            dt = app.TimeStepEditField.Value;
            [z3,z1,z2,transitionPoint,z] = ShootingMethod(u0,theta,phi,r0,dt);
            plot3(app.UIAxes,z3(5,:),z3(6,:),z3(7,:),'k');
            plot3(app.UIAxes,z1(5,:),z1(6,:),z1(7,:),'b');
            plot3(app.UIAxes,z2(5,:),z2(6,:),z2(7,:),'r');
            plot3(app.UIAxes,transitionPoint(1),transitionPoint(2),transitionPoint(3),'*');
            app.FinalAngleEditField.Value = z;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.UIAxes = uiaxes(app.CalculationTab);
            title(app.UIAxes, 'Trajectory of Shuttle')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Height')
            app.msLabel = uilabel(app.CalculationTab);
            app.msLabel.Text = 'm/s';
            app.InitialCoordinatesLabel = uilabel(app.CalculationTab);
            app.InitialCoordinatesLabel.Text = 'Initial Co-ordinates';
            app.FinalCoordinatesLabel = uilabel(app.CalculationTab);
            app.FinalCoordinatesLabel.Text = 'Final Co-ordinates';
            app.CalculateButton = uibutton(app.CalculationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VelocityEditField = uieditfield(app.CalculationTab, 'numeric');
            app.VelocityEditField.Limits = [0 120];
            app.VelocityEditField.Tooltip = {'Please Enter a velocity in m/s'; ' that is below 120m/s'};
            app.VelocityEditField.Value = 75;
            app.VelocityEditFieldLabel = uilabel(app.CalculationTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.xEditFieldLabel = uilabel(app.CalculationTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.CalculationTab, 'numeric');
            app.xEditField.Limits = [-6.7 0];
            app.xEditField.Tooltip = {'Please Enter the distance from the net which the serve it hit. Must be between -6.7 and 0.'};
            app.xEditField.Value = -2.1;
            app.yEditFieldLabel = uilabel(app.CalculationTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.CalculationTab, 'numeric');
            app.yEditField.Limits = [-3.5 3.5];
            app.yEditField.Tooltip = {'please Eneter the distance from the centre of the court the serve is to be hit from. Must be between -3.5 and 3.5'};
            app.yEditField.Value = 1;
            app.FirstGuessEditFieldLabel = uilabel(app.CalculationTab);
            app.FirstGuessEditFieldLabel.Text = 'First Guess';
            app.FirstGuessEditField = uieditfield(app.CalculationTab, 'numeric');
            app.FirstGuessEditField.Limits = [0 90];
            app.FirstGuessEditField.Tooltip = {'Please Enter a Guess for the angle at which the shuttle must be hit. This should be between 0 and 90 degrees.'};
            app.FirstGuessEditField.Value = 20;
            app.SecondGuessEditFieldLabel = uilabel(app.CalculationTab);
            app.SecondGuessEditFieldLabel.Text = 'Second Guess';
            app.SecondGuessEditField = uieditfield(app.CalculationTab, 'numeric');
            app.SecondGuessEditField.Limits = [0 90];
            app.SecondGuessEditField.Tooltip = {'Please Enter a second guess which differs from your first. Value between 0 and 90 degrees'};
            app.SecondGuessEditField.Value = 50;
            app.zEditFieldLabel = uilabel(app.CalculationTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.CalculationTab, 'numeric');
            app.zEditField.Limits = [0 3];
            app.zEditField.Editable = 'off';
            app.zEditField.Value = 1;
            app.xEditField_2Label = uilabel(app.CalculationTab);
            app.xEditField_2Label.Text = 'x';
            app.xFinalEditField = uieditfield(app.CalculationTab, 'numeric');
            app.xFinalEditField.Limits = [0 6.7];
            app.xFinalEditField.Tooltip = {'Please enter the distance from the net on their half of the court it is to be served to. Must be between 0 and 6.7.'};
            app.xFinalEditField.Value = 2.1;
            app.yEditField_2Label = uilabel(app.CalculationTab);
            app.yEditField_2Label.Text = 'y';
            app.yFinalEditField = uieditfield(app.CalculationTab, 'numeric');
            app.yFinalEditField.Editable = 'off';
            app.zEditField_2Label = uilabel(app.CalculationTab);
            app.zEditField_2Label.Text = 'z';
            app.zFinalEditField = uieditfield(app.CalculationTab, 'numeric');
            app.zFinalEditField.Editable = 'off';
            app.FinalAngleEditFieldLabel = uilabel(app.CalculationTab);
            app.FinalAngleEditFieldLabel.Text = 'Final Angle';
            app.FinalAngleEditField = uieditfield(app.CalculationTab, 'numeric');
            app.TimeStepEditFieldLabel = uilabel(app.CalculationTab);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.CalculationTab, 'numeric');
            app.TimeStepEditField.Limits = [0 0.3];
            app.TimeStepEditField.Tooltip = {'Please Enter the time step you wish to use for the appriximation. This must be between 0.0001 and 0.3.'};
            app.TimeStepEditField.Value = 0.0001;
            app.YawEditFieldLabel = uilabel(app.CalculationTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.CalculationTab, 'numeric');
            app.YawEditField.Limits = [-90 90];
            app.YawEditField.Tooltip = {'Please enter the horizontal angel the shuttle is to be hit at. Enter a value between -90 and 90 degrees.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = finishedGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end